classdef Figure1B < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        V1SliderLabel           matlab.ui.control.Label
        V1Slider                matlab.ui.control.Slider
        V2SliderLabel           matlab.ui.control.Label
        V2Slider                matlab.ui.control.Slider
        V3SliderLabel           matlab.ui.control.Label
        V3Slider                matlab.ui.control.Slider
        KSliderLabel            matlab.ui.control.Label
        KSlider                 matlab.ui.control.Slider
        sigmaHSliderLabel       matlab.ui.control.Label
        sigmaHSlider            matlab.ui.control.Slider
        wSliderLabel            matlab.ui.control.Label
        wSlider                 matlab.ui.control.Slider
        sigma_fixedSliderLabel  matlab.ui.control.Label
        sigma_fixedSlider       matlab.ui.control.Slider
        SSliderLabel            matlab.ui.control.Label
        SSlider                 matlab.ui.control.Slider
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        V1Button                matlab.ui.control.StateButton
        V2Button                matlab.ui.control.StateButton
        V3Button                matlab.ui.control.StateButton
        KaSliderLabel           matlab.ui.control.Label
        KaSlider                matlab.ui.control.Slider
    end

    
    methods (Access = private)
        
        function showit(app)
            V1 = app.V1Slider.Value;
            V2= app.V2Slider.Value;
            V3=app.V3Slider.Value;
            V=[V1 V2 V3];
            K=app.KSlider.Value;
            Ka = app.KaSlider.Value;
            sigmaH=app.sigmaHSlider.Value;
            w=app.wSlider.Value;
            sigmafixed=app.sigma_fixedSlider.Value;
            S=app.SSlider.Value;
            firing_rate_mean1=K*V1/(sigmaH+sum(w.*V));
            firing_rate_mean2=K*V2/(sigmaH+sum(w.*V));
            firing_rate_mean3=K*V3/(sigmaH+sum(w.*V));
            ffiring_rate_mean1=Ka*V1;
            ffiring_rate_mean2=Ka*V2;
            ffiring_rate_mean3=Ka*V3;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            xlim(app.UIAxes,[0 100]);
            xlim(app.UIAxes2,[0 100]);
            if (app.V1Button.Value)
                drawfun(app.UIAxes,firing_rate_mean1,sigmafixed,S,'b');
                drawfun(app.UIAxes2,ffiring_rate_mean1,sigmafixed,S,'b');
            end
            if (app.V2Button.Value)
                hold(app.UIAxes,"on");
                hold(app.UIAxes2,"on");
                drawfun(app.UIAxes,firing_rate_mean2,sigmafixed,S,'k');
                drawfun(app.UIAxes2,ffiring_rate_mean2,sigmafixed,S,'k');
            end
            if (app.V3Button.Value)
                hold(app.UIAxes,"on");
                hold(app.UIAxes2,"on");
                drawfun(app.UIAxes,firing_rate_mean3,sigmafixed,S,'r');
                drawfun(app.UIAxes2,ffiring_rate_mean3,sigmafixed,S,'r');
            end
            hold(app.UIAxes,"off");
            hold(app.UIAxes2,"off");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: V1Slider
        function V1SliderValueChanged(app, event)
            showit(app);
        end

        % Value changed function: V2Slider
        function V2SliderValueChanged(app, event)
            showit(app);
        end

        % Value changed function: V3Slider
        function V3SliderValueChanged(app, event)
            showit(app);
        end

        % Value changed function: KSlider
        function KSliderValueChanged(app, event)
            showit(app);
        end

        % Value changed function: sigmaHSlider
        function sigmaHSliderValueChanged(app, event)
            showit(app);
        end

        % Value changed function: wSlider
        function wSliderValueChanged(app, event)
            showit(app);
            
        end

        % Value changed function: sigma_fixedSlider
        function sigma_fixedSliderValueChanged(app, event)
            showit(app);
            
        end

        % Value changed function: SSlider
        function SSliderValueChanged(app, event)
            showit(app);
        end

        % Value changed function: V1Button
        function V1ButtonValueChanged(app, event)

            showit(app);
        end

        % Value changed function: V2Button
        function V2ButtonValueChanged(app, event)
            showit(app);
        end

        % Value changed function: V3Button
        function V3ButtonValueChanged(app, event)
            showit(app);
        end

        % Value changed function: KaSlider
        function KaSliderValueChanged(app, event)
            showit(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.V1SliderLabel = uilabel(app.UIFigure);
            app.V1SliderLabel.Text = 'V1';
            app.V1Slider = uislider(app.UIFigure);
            app.V1Slider.Limits = [100 150];
            app.V1Slider.ValueChangedFcn = createCallbackFcn(app, @V1SliderValueChanged, true);
            app.V1Slider.Value = 100;
            app.V2SliderLabel = uilabel(app.UIFigure);
            app.V2SliderLabel.Text = 'V2';
            app.V2Slider = uislider(app.UIFigure);
            app.V2Slider.Limits = [100 150];
            app.V2Slider.ValueChangedFcn = createCallbackFcn(app, @V2SliderValueChanged, true);
            app.V2Slider.Value = 100;
            app.V3SliderLabel = uilabel(app.UIFigure);
            app.V3SliderLabel.Text = 'V3';
            app.V3Slider = uislider(app.UIFigure);
            app.V3Slider.Limits = [0 200];
            app.V3Slider.ValueChangedFcn = createCallbackFcn(app, @V3SliderValueChanged, true);
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.ValueChangedFcn = createCallbackFcn(app, @KSliderValueChanged, true);
            app.sigmaHSliderLabel = uilabel(app.UIFigure);
            app.sigmaHSliderLabel.Text = 'sigmaH';
            app.sigmaHSlider = uislider(app.UIFigure);
            app.sigmaHSlider.ValueChangedFcn = createCallbackFcn(app, @sigmaHSliderValueChanged, true);
            app.wSliderLabel = uilabel(app.UIFigure);
            app.wSliderLabel.Text = 'w';
            app.wSlider = uislider(app.UIFigure);
            app.wSlider.Limits = [0 1];
            app.wSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.wSlider.MajorTickLabels = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1'};
            app.wSlider.ValueChangedFcn = createCallbackFcn(app, @wSliderValueChanged, true);
            app.sigma_fixedSliderLabel = uilabel(app.UIFigure);
            app.sigma_fixedSliderLabel.Text = 'sigma_fixed';
            app.sigma_fixedSlider = uislider(app.UIFigure);
            app.sigma_fixedSlider.Limits = [0 10];
            app.sigma_fixedSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.sigma_fixedSlider.ValueChangedFcn = createCallbackFcn(app, @sigma_fixedSliderValueChanged, true);
            app.SSliderLabel = uilabel(app.UIFigure);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.UIFigure);
            app.SSlider.Limits = [0 10];
            app.SSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SSlider.ValueChangedFcn = createCallbackFcn(app, @SSliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Relative Value')
            xlabel(app.UIAxes, 'Firing Rate(sp/s)')
            ylabel(app.UIAxes, 'Probability density')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Absolute Value')
            xlabel(app.UIAxes2, {'Firing Rate(sp/s)'; ''})
            ylabel(app.UIAxes2, 'Probability density')
            app.UIAxes2.XLim = [0 100];
            app.UIAxes2.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes2.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.V1Button = uibutton(app.UIFigure, 'state');
            app.V1Button.ValueChangedFcn = createCallbackFcn(app, @V1ButtonValueChanged, true);
            app.V1Button.Text = 'V1';
            app.V2Button = uibutton(app.UIFigure, 'state');
            app.V2Button.ValueChangedFcn = createCallbackFcn(app, @V2ButtonValueChanged, true);
            app.V2Button.Text = 'V2';
            app.V3Button = uibutton(app.UIFigure, 'state');
            app.V3Button.ValueChangedFcn = createCallbackFcn(app, @V3ButtonValueChanged, true);
            app.V3Button.Text = 'V3';
            app.KaSliderLabel = uilabel(app.UIFigure);
            app.KaSliderLabel.Text = 'Ka';
            app.KaSlider = uislider(app.UIFigure);
            app.KaSlider.Limits = [0 1];
            app.KaSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.KaSlider.MajorTickLabels = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1'};
            app.KaSlider.ValueChangedFcn = createCallbackFcn(app, @KaSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Figure1B

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end