classdef Interference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WMmodel             matlab.ui.Figure
        Parameters          matlab.ui.container.Panel
        aSliderLabel        matlab.ui.control.Label
        aSlider             matlab.ui.control.Slider
        bSliderLabel        matlab.ui.control.Label
        bSlider             matlab.ui.control.Slider
        SliderLabel         matlab.ui.control.Label
        kSlider             matlab.ui.control.Slider
        fSliderLabel        matlab.ui.control.Label
        kfSlider            matlab.ui.control.Slider
        sSliderLabel        matlab.ui.control.Label
        sSlider             matlab.ui.control.Slider
        rSliderLabel        matlab.ui.control.Label
        rSlider             matlab.ui.control.Slider
        Disp                matlab.ui.control.UIAxes
        yAx                 matlab.ui.control.UIAxes
        Bind                matlab.ui.control.UIAxes
        Colorbar            matlab.ui.control.UIAxes
        xAx                 matlab.ui.control.UIAxes
        NewitemButton       matlab.ui.control.Button
        CueTypeButtonGroup  matlab.ui.container.ButtonGroup
        NoneButton          matlab.ui.control.RadioButton
        PreButton           matlab.ui.control.RadioButton
        RetroButton         matlab.ui.control.RadioButton
        DisplayButton       matlab.ui.control.Button
        RetentionButton     matlab.ui.control.Button
        CueButton           matlab.ui.control.Button
        TestButton          matlab.ui.control.Button
        ClearButton         matlab.ui.control.Button
        InterferenceModelforWorkingMemoryLabel  matlab.ui.control.Label
        byRuiqiChenLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Mousedown = false; % Whether the mouse button is down
        allPos = [];  % Angle of the stimuli
        allCol = [];  % Color of the stimuli
        cuedInd = 0;  % Index of the cued position
        testedInd = 0; % Index of the tested position
        rfRate = 10;   % Refresh rate
        stiSize = 0.3; % Stimulus size
        dispr = 1;     % Inner radius for stimulus position ring
        dispR = 1.2;   % Outer radius
        cNoiseRat = 0.05;  % noise / activation in context space
        nBin = 360;    % Number of bins for everything
        Stage = 'Init'; % Current stage
    end
    
    methods (Static)
        
        function results = vonMises(x, c, kappa)
            results = exp(kappa * cosd(x - c)) / ...
                (2 * pi * besseli(0, kappa));
        end
    end
    
    methods (Static)
        
        function results = expWeight(li, lt, s)
            results = exp(-s * abs(li - lt) / 360 * 2 * pi);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function Init(app)
            
            % Colorbar
            x = 0:app.nBin;
            c = hsv(length(x) - 1);
            hold(app.Colorbar, 'on');
            for i = 1:length(x) - 1
                fill(app.Colorbar, [x(i) x(i+1) x(i+1) x(i)], [0 0 1 1], ...
                    c(i,:), 'EdgeColor', 'none');
            end
            axis(app.Colorbar, 'tight', 'off');
            
            % Instruction
            title(app.Disp, 'Click "New item" to add stimuli');
            
            % Default Parameters
            app.aSlider.Value = 0.18;
            app.bSlider.Value = 0.15;
            app.kSlider.Value = 20;
            app.kfSlider.Value = 9;
            app.sSlider.Value = 9;
            app.rSlider.Value = 0.3;
            
            
        end

        % Button pushed function: NewitemButton
        function NewitemButtonPushed(app, event)

            % Constants
            cRing = 0.1;    % Alpha of the selection ring
            bindBl = 0.1;   % Baseline binding weights (proportional)
            bScale = 0.35;   % Scale the binding weights by this exponential
            alphaOld = 0.2;  % Alpha of the old activations
            
            cla(app.Disp);
            
            % Binding space
            bMax = Interference.vonMises(0, 0, app.kSlider.Value) ^ (2 * bScale);
            oriB = bMax * ones(app.nBin);
            if isempty(app.Bind.Children)
                colormap(app.Bind, 'gray');
                imagesc(app.Bind, 0:(app.nBin - 1), 0:(app.nBin - 1), oriB, [0 bMax * (1 + bindBl)]);
            else
                oriB = app.Bind.Children(1).CData;
            end
            axis(app.Bind, 'tight');
            hold(app.Bind, 'on');
            
            % Display item location
            theta = 0:1:360;            
            allX = [app.dispr * cosd(theta) app.dispR * cosd(theta(end:-1:1))];
            allY = [app.dispr * sind(theta) app.dispR * sind(theta(end:-1:1))];
            
            hold(app.Disp, 'on');
            fill(app.Disp, allX, allY, [1 1 1] - cRing, ...
                'EdgeColor', 'none');
            ylim(app.Disp, [-app.dispR * 1.5, app.dispR * 1.5]);
            axis(app.Disp, 'equal');
            tmp = app.Disp.InnerPosition;
            centerPos = [tmp(1) + tmp(3) / 2, tmp(2) + tmp(4) / 2];
            
            % Ring
            newTheta = -5:5;
            newX = [app.dispr * cosd(newTheta) app.dispR * cosd(newTheta(end:-1:1))];
            newY = [app.dispr * sind(newTheta) app.dispR * sind(newTheta(end:-1:1))];
            fill(app.Disp, newX, newY, 'r', 'FaceAlpha', 0.5, ...
                'EdgeColor', 'none');  
            title(app.Disp, 'Click to select a location');
            
            % Activation
            pk = app.kSlider.Value;
            % CAUTION: yAx has reversed axes!
            % x = -yReal; y = xReal
            yAxY = 0:(app.nBin - 1);
            cNoise = -app.cNoiseRat * Interference.vonMises(0, 0, pk);
            hold(app.yAx, 'on');
            plot(app.yAx, cNoise * ones(1, app.nBin), yAxY, 'k--');
            plot(app.yAx, cNoise - Interference.vonMises(yAxY, 0, pk), yAxY, 'k');
            axis(app.yAx, 'tight');
            xlim(app.yAx, [-Interference.vonMises(0, 0, pk) + cNoise 0]);
            
            % Binding
            % CAUTION: imagesc has reversed y axis!
            plot(app.Bind, 0:(app.nBin - 1), (app.nBin - 1) - 0 * ones(1, app.nBin), '--k');
            
            app.Mousedown = false;
            while 1
                
                currPos = app.WMmodel.CurrentPoint;
                currOri = currPos - centerPos;
                currAng = acos(currOri(1) / sqrt(sum(currOri .^ 2)));
                if currOri(2) < 0
                    currAng = 2 * pi - currAng;
                end
                currAng = round(currAng / 2 / pi * 360);
                
                % Ring
                newTheta = currAng - 5:currAng + 5;
                newX = [app.dispr * cosd(newTheta) app.dispR * cosd(newTheta(end:-1:1))];
                newY = [app.dispr * sind(newTheta) app.dispR * sind(newTheta(end:-1:1))];
                app.Disp.Children(1).Vertices = [newX' newY'];
                ylim(app.Disp, [-app.dispR * 1.5, app.dispR * 1.5]);
                axis(app.Disp, 'equal');
                
                % Activation
                pk = app.kSlider.Value;
                yAxX = -Interference.vonMises(yAxY, currAng, pk);
                app.yAx.Children(1).XData = yAxX + cNoise;
                xlim(app.yAx, [-Interference.vonMises(0, 0, pk) + cNoise 0]);
                
                % Binding
                app.Bind.Children(1).YData = app.nBin - currAng * ones(1, app.nBin);
                
                % Check mouse click
                if app.Mousedown
                    app.Mousedown = false;
                    break;
                end

                pause(1/app.rfRate);
            end
            cAngle = currAng;
            app.allPos = [app.allPos cAngle];
            
            % Display color ring
            cla(app.Disp);
            rColor = hsv(length(theta) - 1);
            allX = [app.dispr * cosd(theta(1:end - 1)); app.dispr * cosd(theta(2:end)); ...
                app.dispR * cosd(theta(2:end)); app.dispR * cosd(theta(1:end - 1))];
            allY = [app.dispr * sind(theta(1:end - 1)); app.dispr * sind(theta(2:end)); ...
                app.dispR * sind(theta(2:end)); app.dispR * sind(theta(1:end - 1))];
            for i = 1:length(allX)
                fill(app.Disp, allX(:, i), allY(:, i), rColor(i, :), ...
                    'EdgeColor', 'none');
            end
            ylim(app.Disp, [-app.dispR * 1.5, app.dispR * 1.5]);
            axis(app.Disp, 'equal');
            title(app.Disp, 'Click to select a color');
            
            % Ring
            newTheta = -5:5;
            newX = [app.dispr * cosd(newTheta) app.dispR * cosd(newTheta(end:-1:1))];
            newY = [app.dispr * sind(newTheta) app.dispR * sind(newTheta(end:-1:1))];
            fill(app.Disp, newX, newY, 0, 'FaceColor', 'none', ...
                'EdgeColor', [1 1 1] - 0.1, 'LineWidth', 1);  
            
            % Activation
            pk = app.kSlider.Value;
            pb = app.bSlider.Value;
%             ps = app.sSlider.Value;
            xAxX = 0:(app.nBin - 1);
            mNoise = pb / 2 / pi;
            hold(app.xAx, 'on');
            plot(app.xAx, xAxX, mNoise * ones(1, app.nBin), 'k--');
            plot(app.xAx, xAxX, mNoise + ...
                Interference.vonMises(xAxX, 0, pk), 'k');
            axis(app.xAx, 'tight');
            ylim(app.xAx, [0 Interference.vonMises(0, 0, pk) + mNoise]);
            
            % Binding
            plot(app.Bind, 0 * ones(1, app.nBin), 0:(app.nBin - 1), '--k');
            app.Bind.Children(3).CData = oriB -  ...
                (Interference.vonMises(yAxY(end:-1:1)', cAngle, pk) ...
                    * Interference.vonMises(xAxX, 0, pk)) .^ bScale;
            
            app.Mousedown = false;
            while 1
                
                currPos = app.WMmodel.CurrentPoint;
                currOri = currPos - centerPos;
                currAng = acos(currOri(1) / sqrt(sum(currOri .^ 2)));
                if currOri(2) < 0
                    currAng = 2 * pi - currAng;
                end
                currAng = round(currAng / 2 / pi * 360);
                
                % Ring
                newTheta = currAng - 5:currAng + 5;
                newX = [app.dispr * cosd(newTheta) app.dispR * cosd(newTheta(end:-1:1))];
                newY = [app.dispr * sind(newTheta) app.dispR * sind(newTheta(end:-1:1))];
                app.Disp.Children(1).Vertices = [newX' newY'];
                ylim(app.Disp, [-app.dispR * 1.5, app.dispR * 1.5]);
                axis(app.Disp, 'equal');
                
                % Activation
                pk = app.kSlider.Value;
                xAxY = Interference.vonMises(xAxX, currAng, pk);
                app.xAx.Children(1).YData = mNoise + xAxY;
                ylim(app.xAx, [0 Interference.vonMises(0, 0, pk) + mNoise]);
                
                % Binding
                app.Bind.Children(1).XData = currAng * ones(1, app.nBin);
                app.Bind.Children(3).CData = oriB -  ...
                    (Interference.vonMises(yAxY(end:-1:1)', cAngle, pk) *...
                        Interference.vonMises(xAxX, currAng, pk)) .^ bScale;
            
                
                % Check mouse click
                if app.Mousedown
                    app.Mousedown = false;
                    break;
                end

                pause(1/app.rfRate);
            end            
            app.allCol = [app.allCol currAng];
            
            % Present instuction
            cla(app.Disp);
            title(app.Disp, ...
                sprintf('Add new item or\nClick "Display" to display stimuli'));
            
            % Sum up the activation and make it grey
            delete(app.yAx.Children(2));  % The noise dashline
            app.yAx.Children(1).Color = [1 1 1] - alphaOld;
            delete(app.xAx.Children(2));
            app.xAx.Children(1).Color = [1 1 1] - alphaOld;
            
            % Delete extra lines in binding space
            delete(app.Bind.Children(1));
            delete(app.Bind.Children(1));
            
            app.Stage = 'New item';
                        
        end

        % Window button motion function: WMmodel
        function WMmodelWindowButtonMotion(app, event)
            % You must specify this function to track the mouse position
        end

        % Window button down function: WMmodel
        function WMmodelWindowButtonDown(app, event)
            app.Mousedown = true;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.Disp);
            cla(app.xAx);
            cla(app.yAx);
            cla(app.Bind);
            app.allPos = [];
            app.allCol = [];
            app.Mousedown = false;
            app.cuedInd = 0;
            app.testedInd = 0;
            app.Stage = 'Init';
            legend(app.xAx, 'off');
            title(app.Disp, 'Click "New item" to add stimuli');
            
            % Default Parameters
            app.aSlider.Value = 0.18;
            app.bSlider.Value = 0.15;
            app.kSlider.Value = 20;
            app.kfSlider.Value = 9;
            app.sSlider.Value = 9;
            app.rSlider.Value = 0.3;            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)

            % Constants
            col = hsv(app.nBin);  % Color
            pk = app.kSlider.Value;
            pb = app.bSlider.Value;
%             ps = app.sSlider.Value;
            
            % Display
            cla(app.Disp);
            plot(app.Disp, 0, 0, '+k');
            for i = 1:length(app.allPos)
                currPos = [cosd(app.allPos(i)) sind(app.allPos(i))] * app.dispR;
                fill(app.Disp, [currPos(1) - app.stiSize / 2, currPos(1) + app.stiSize / 2, ...
                    currPos(1) + app.stiSize / 2, currPos(1) - app.stiSize / 2], ...
                    [currPos(2) - app.stiSize / 2, currPos(2) - app.stiSize / 2, ...
                    currPos(2) + app.stiSize / 2, currPos(2) + app.stiSize / 2], ...
                    col(ceil(app.allPos(i)), :));
            end
            ylim(app.Disp, [-app.dispR * 1.5, app.dispR * 1.5]);
            tmp = 1.5 / app.Disp.InnerPosition(4) * app.Disp.InnerPosition(3);
            xlim(app.Disp, [-app.dispR * tmp, app.dispR * tmp]);
            if app.NoneButton.Value || app.RetroButton.Value
                title(app.Disp, 'Click "Retention" to hold the items');
            else
                title(app.Disp, 'Click "Cue" to cue an item');
            end
            
            % Context space
            nSti = length(app.allPos);
            cNoise = Interference.vonMises(0, 0, pk) * app.cNoiseRat * nSti;
            yAxX = zeros(1, app.nBin) - cNoise;
            yAxY = 0:(app.nBin - 1);
            for i = 1:nSti
                yAxX = yAxX - Interference.vonMises(yAxY, app.allPos(i), pk);
            end
            cla(app.yAx);
            plot(app.yAx, yAxX, yAxY, 'k');
            axis(app.yAx, 'tight');
            xlim(app.xAx, [min(yAxX) 0]);

            % Memory space
            nSti = length(app.allPos);
            fNoise = pb * nSti / 2 / pi;
            xAxY = zeros(1, app.nBin) + fNoise;
            xAxX = 0:(app.nBin - 1);
            for i = 1:nSti
                xAxY = Interference.vonMises(xAxX, app.allCol(i), pk) + xAxY;
            end
            cla(app.xAx);
            plot(app.xAx, xAxX, xAxY, 'k');
            axis(app.xAx, 'tight');
            ylim(app.xAx, [0, fNoise + max(xAxY)]);

            app.Stage = 'Display';
                        
        end

        % Button pushed function: CueButton
        function CueButtonPushed(app, event)
             
            % Constants
            pkf = app.kfSlider.Value;
            pk = app.kSlider.Value;
            pa = app.aSlider.Value;
            pb = app.bSlider.Value;
            pr = app.rSlider.Value;
            nSti = length(app.allCol);
            blurRat = 1.2;  % For mouse click tolerance
            col = hsv(app.nBin);
            xAxX = 0:(app.nBin - 1);
            
            if app.NoneButton.Value
                title(app.Disp, 'Select a cue type first');
                return
            elseif app.RetroButton.Value && ~strcmp(app.Stage, 'Retention')
                title(app.Disp, 'Click "Retention" first');
                return
            end
            title(app.Disp, 'Click to select a stimulus');
            
            cla(app.Disp);
            plot(app.Disp, 0, 0, '+k');
            for i = 1:length(app.allPos)
                currPos = [cosd(app.allPos(i)) sind(app.allPos(i))] * app.dispR;
                if app.PreButton.Value
                    currCol = col(ceil(app.allPos(i)), :);
                    currLine = 'none';
                else
                    currCol = [0 0 0];
                    currLine = '--';
                end
                fill(app.Disp, [currPos(1) - app.stiSize / 2, currPos(1) + app.stiSize / 2, ...
                    currPos(1) + app.stiSize / 2, currPos(1) - app.stiSize / 2], ...
                    [currPos(2) - app.stiSize / 2, currPos(2) - app.stiSize / 2, ...
                    currPos(2) + app.stiSize / 2, currPos(2) + app.stiSize / 2], ...
                    currCol, 'LineStyle', currLine);
            end
            ylim(app.Disp, [-app.dispR * 1.5, app.dispR * 1.5]);
            tmp = 1.5 / app.Disp.InnerPosition(4) * app.Disp.InnerPosition(3);
            xlim(app.Disp, [-app.dispR * tmp, app.dispR * tmp]);
            
            % Memory activation
            if app.PreButton.Value
                app.xAx.Children(1).LineStyle = '--';
            else
                mNoise = nSti / 2 / pi * pb;
                mAct = zeros(1, app.nBin);
                for i = 1:nSti
                    mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
                end      
                mCue = Interference.vonMises(xAxX, app.allCol(end), pkf);
%                 oldY = app.xAx.Children(3).YData;
            end
            
            tmp = app.Disp.InnerPosition;
            centerPos = [tmp(1) + tmp(3) / 2, tmp(2) + tmp(4) / 2]; 
            currYlim = ylim(app.Disp);
            scaleDisp = (currYlim(2) - currYlim(1)) / tmp(4);
            
            currInd = 0;
            app.Mousedown = false;
            while 1
                
                currPos = app.WMmodel.CurrentPoint;
                currPos = (currPos - centerPos) * scaleDisp;
                
                for i = 1:nSti
                    tmp = app.dispR * [cosd(app.allPos(i)), sind(app.allPos(i))];
                    if max(abs(tmp - currPos)) <= app.stiSize / 2 * blurRat
                        
                        % Display
                        currInd = i;
                        app.Disp.Children(nSti + 1 - i).FaceAlpha = 0.2;
                        title(app.Disp, sprintf('Item %d cued', currInd));
                        
                        % Binding
                        if length(app.Bind.Children) == 1
                            plot(app.Bind, xAxX, ones(1, app.nBin) *...
                                (app.nBin - 1 - app.allPos(currInd)), '--k');
                            plot(app.Bind, ones(1, app.nBin) * app.allCol(currInd), ...
                                xAxX, '--k');
                        else
                            app.Bind.Children(1).XData = ones(1, app.nBin) * app.allCol(currInd);
                            app.Bind.Children(2).YData = ones(1, app.nBin) * ...
                                (app.nBin - 1 - app.allPos(currInd));
                        end

                        % Precue
                        if app.PreButton.Value
                            if length(app.xAx.Children) == 1
                                plot(app.xAx, 0:(app.nBin - 1), Interference.vonMises(0:(app.nBin - 1), ...
                                    app.allCol(currInd), pkf), 'k');
                            else
                                app.xAx.Children(1).YData = Interference.vonMises(0:(app.nBin - 1), ...
                                    app.allCol(currInd), pkf);
                            end
                            legend(app.xAx, {'Activation', 'Info gain'});
                        else  % Retrocue
                            app.xAx.Children(3).YData = pr * (mNoise + mAct) + ...
                                mCue * (currInd == nSti);
                        end
                        
                    end
                end
                
                % Check mouse click
                if app.Mousedown
                    app.Mousedown = false;
                    break;
                end

                pause(1/app.rfRate);
                if currInd
                    app.Disp.Children(length(app.allCol) + 1 - currInd).FaceAlpha = 1;
%                     if app.RetroButton.Value
%                         app.xAx.Children(3).YData = oldY;
%                     else
%                         app.xAx.Children(1).YData = zeros(1, app.nBin);
%                     end
                    currInd = 0;
                end
            end   
            app.cuedInd = currInd;
            
            % Delete the lines
            delete(app.Bind.Children(1));
            delete(app.Bind.Children(1));
            
            % Instruction
            if app.PreButton.Value
%                 app.xAx.Children(1).LineStyle = '--';
%                 plot(app.xAx, 0:(app.nBin - 1), Interference.vonMises(0:(app.nBin - 1), ...
%                     app.allCol(app.cuedInd), kf), 'k');
                title(app.Disp, 'Click "Retention" to hold the memories');
                app.Stage = 'Cue';
            else
%                 app.RetentionButtonPushed();
                for i = 1:length(app.allCol)
                    delete(app.Disp.Children(1));
                end
                currPos = [cosd(app.allPos(app.cuedInd)) sind(app.allPos(app.cuedInd))] * app.dispR;
                fill(app.Disp, [currPos(1) - app.stiSize / 2, currPos(1) + app.stiSize / 2, ...
                    currPos(1) + app.stiSize / 2, currPos(1) - app.stiSize / 2], ...
                    [currPos(2) - app.stiSize / 2, currPos(2) - app.stiSize / 2, ...
                    currPos(2) + app.stiSize / 2, currPos(2) + app.stiSize / 2], ...
                    [1 1 1], 'LineStyle','--');
                
                title(app.Disp, 'Click "Test" to test the memory');
                cla(app.yAx);
                plot(app.yAx, -app.cNoiseRat * ones(1, app.nBin), xAxX, '--k');
                xlim(app.yAx, [-1 0]);
                app.Stage = 'Retention';
            end
            
        end

        % Button pushed function: RetentionButton
        function RetentionButtonPushed(app, event)
            
            % Constants
            pa = app.aSlider.Value;
            pb = app.bSlider.Value;
            pr = app.rSlider.Value;
            pk = app.kSlider.Value;
            pkf = app.kfSlider.Value;
            nSti = length(app.allPos);
            xAxX = 0:(app.nBin - 1);
            currYlim = ylim(app.xAx);
                        
            if ~strcmp(app.Stage, 'Cue')  && app.PreButton.Value
                title(app.Disp, 'Click "Cue" to cue an item first');
                return
            end
            
            % Clean up
            for i = 1:length(app.allCol)
                delete(app.Disp.Children(1));
            end
            cla(app.yAx);
            plot(app.yAx, -app.cNoiseRat * ones(1, app.nBin), xAxX, '--k');
            xlim(app.yAx, [-1 0]);
            
            % Neutral condition
            if ~strcmp(app.Stage, 'Cue')  % none or before cue
                title(app.Disp, 'Attending to the last item');
                app.cuedInd = nSti;
            end
            currPos = [cosd(app.allPos(app.cuedInd)) sind(app.allPos(app.cuedInd))] * app.dispR;
            fill(app.Disp, [currPos(1) - app.stiSize / 2, currPos(1) + app.stiSize / 2, ...
                currPos(1) + app.stiSize / 2, currPos(1) - app.stiSize / 2], ...
                [currPos(2) - app.stiSize / 2, currPos(2) - app.stiSize / 2, ...
                currPos(2) + app.stiSize / 2, currPos(2) + app.stiSize / 2], ...
                [1 1 1], 'LineStyle','--');
            
            % With or without information gain
            if app.cuedInd == nSti || app.PreButton.Value
                mCue = Interference.vonMises(xAxX, ...
                    app.allCol(app.cuedInd), pkf);
            else
                mCue = 0;
            end
            
            cla(app.xAx);
            mNoise = nSti / 2 / pi * pb;
            mAct = zeros(1, app.nBin);
            for i = 1:nSti
                mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
            end
            plot(app.xAx, xAxX, pr * mNoise + pr * mAct + mCue, 'k');
            plot(app.xAx, xAxX, mNoise + mAct, '--k');
            plot(app.xAx, xAxX, mNoise * ones(1, app.nBin), '--k');
            axis(app.xAx, 'tight');
            ylim(app.xAx, currYlim);
            legend(app.xAx, {'Attended', 'Unattended', 'Noise'});
            
            if app.RetroButton.Value
                title(app.Disp, sprintf('Attending to the last item\nClick "Cue" to cue an item'));
            elseif app.NoneButton.Value
                title(app.Disp, sprintf('Attending to the last item\nClick "Test" to test the memory'));
            else
                title(app.Disp, 'Click "Test" to test the memory');
            end
            
            app.Stage = 'Retention';
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            
            % Constants
            pk = app.kSlider.Value;
            ps = app.sSlider.Value;
            pb = app.bSlider.Value;
            pkf = app.kfSlider.Value;
            pa = app.aSlider.Value;
            pr = app.rSlider.Value;
            nSti = length(app.allCol);
            blurRat = 1.2;  % For mouse click tolerance
            alphaOld = 0.2;  % Alpha of the old activations
            xAxX = 0:(app.nBin - 1);

            mNoise = nSti / 2 / pi * pb;
            mAct = zeros(1, app.nBin);
            for i = 1:nSti
                mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
            end
            
            cla(app.yAx);
            xlim(app.yAx, [-Interference.vonMises(0, 0, pk) 0])
            
            if ~strcmp(app.Stage, 'Retention')
                title(app.Disp, 'Click "Retention" or "Cue" first');
                return
            end
            title(app.Disp, 'Click to select a stimulus');
            
            cla(app.Disp);
            plot(app.Disp, 0, 0, '+k');
            for i = 1:length(app.allPos)
                currPos = [cosd(app.allPos(i)) sind(app.allPos(i))] * app.dispR;
                fill(app.Disp, [currPos(1) - app.stiSize / 2, currPos(1) + app.stiSize / 2, ...
                    currPos(1) + app.stiSize / 2, currPos(1) - app.stiSize / 2], ...
                    [currPos(2) - app.stiSize / 2, currPos(2) - app.stiSize / 2, ...
                    currPos(2) + app.stiSize / 2, currPos(2) + app.stiSize / 2], ...
                    [0 0 0]);
            end
            ylim(app.Disp, [-app.dispR * 1.5, app.dispR * 1.5]);
            tmp = 1.5 / app.Disp.InnerPosition(4) * app.Disp.InnerPosition(3);
            xlim(app.Disp, [-app.dispR * tmp, app.dispR * tmp]);
            
            % Clean the memory activation noise
            delete(app.xAx.Children(1));
            legend(app.xAx, {'Attended', 'Unattended'});
            for i = 1:2
                app.xAx.Children(i).Color = [1 1 1] - alphaOld;
            end
            
            tmp = app.Disp.InnerPosition;
            centerPos = [tmp(1) + tmp(3) / 2, tmp(2) + tmp(4) / 2]; 
            currYlim = ylim(app.Disp);
            scaleDisp = (currYlim(2) - currYlim(1)) / tmp(4);
            
            currInd = 0;
            app.Mousedown = false;
            while 1
                
                currPos = app.WMmodel.CurrentPoint;
                currPos = (currPos - centerPos) * scaleDisp;
                
                for i = 1:length(app.allCol)
                    tmp = app.dispR * [cosd(app.allPos(i)), sind(app.allPos(i))];
                    if max(abs(tmp - currPos)) <= app.stiSize / 2 * blurRat
                        currInd = i;
                        
                        % Selection
                        title(app.Disp, sprintf('Item %d activated', currInd));
                        app.Disp.Children(length(app.allCol) + 1 - i).FaceAlpha = 0.2;
                        
                        % Activation
                        if isempty(app.yAx.Children)
                            plot(app.yAx, -Interference.vonMises(xAxX, app.allPos(currInd), pk), ...
                                xAxX, 'k');
                        else
                            app.yAx.Children(1).XData = ...
                                -Interference.vonMises(xAxX, app.allPos(currInd), pk);
                        end
                        
                        % Binding
                        if length(app.Bind.Children) == 1
                            plot(app.Bind, xAxX, app.nBin - 1 - app.allPos(currInd) * ones(app.nBin, 1), '--k');
                        else
                            app.Bind.Children(1).YData = app.nBin - 1 - ones(app.nBin, 1) * app.allPos(currInd);
                        end
                        
                        % Memory activation
                        if app.cuedInd == nSti || app.PreButton.Value
                            mCued = Interference.vonMises(xAxX, app.allCol(app.cuedInd), pkf);
                        else
                            mCued = zeros(1, app.nBin);
                        end
                        mTested = zeros(1, app.nBin);
                        for j = 1:nSti
                            mTested = mTested + Interference.expWeight(app.allPos(j), app.allPos(currInd), ps) .*  ...
                                Interference.vonMises(xAxX, app.allCol(j), pk);
                        end
                        if currInd ~= app.cuedInd  % Uncued
                            app.xAx.Children(2).YData = pr * (mAct + mNoise) + mCued;
                            app.xAx.Children(2).Color = [1 1 1] - alphaOld;
                            app.xAx.Children(1).Color = [0 0 0];
                            app.xAx.Children(1).YData = mTested + mAct + mNoise;
                        else  % Cued
                            app.xAx.Children(2).YData = mTested + pr * (mAct + mNoise) + mCued;
                            app.xAx.Children(1).YData = mAct + mNoise;
                            app.xAx.Children(2).Color = [0 0 0];
                            app.xAx.Children(1).Color = [1 1 1] - alphaOld;
                        end
                        
                        break;
                    end
                end
                
                % Check mouse click
                if app.Mousedown
                    app.Mousedown = false;
                    break;
                end

                pause(1/app.rfRate);
                if currInd
                    app.Disp.Children(length(app.allCol) + 1 - currInd).FaceAlpha = 1;
%                     if currInd == app.cuedInd
%                         app.xAx.Children(2).YData = oldY;
%                         app.xAx.Children(2).Color = [1 1 1] - alphaOld;
%                     else
%                         app.xAx.Children(1).YData = oldY;
%                         app.xAx.Children(1).Color = [1 1 1] - alphaOld;
%                     end
%                     ylim(app.xAx, xaxYlim);
                    currInd = 0;
                end
            end   
            app.testedInd = currInd;
            
            app.Stage = 'Test';
            
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            
            % Constants
            pa = event.Value;
            ps = app.sSlider.Value;
            pb = app.bSlider.Value;
            pr = app.rSlider.Value;
            pk = app.kSlider.Value;
            pkf = app.kfSlider.Value;
            nSti = length(app.allPos);
            xAxX = 0:(app.nBin - 1);
            
            mNoise = nSti / 2 / pi * pb;
            mAct = zeros(1, app.nBin);
            for i = 1:nSti
                mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
            end
            
            switch app.Stage
                case {'Init', 'New item', 'Display', 'Cue'}
                    return
                case 'Retention'
                    
                    if length(app.xAx.Children) ~= 3  % During testing
                        return
                    end
                    
%                     currYlim = ylim(app.xAx);
                    
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCue = Interference.vonMises(xAxX, ...
                            app.allCol(app.cuedInd), pkf);
                    else
                        mCue = 0;
                    end                    
                    
                    app.xAx.Children(3).YData = pr * (mNoise + mAct) + mCue;
                    app.xAx.Children(2).YData = mAct + mNoise;
%                     ylim(app.xAx, currYlim);
                    
                case 'Test'
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCued = Interference.vonMises(xAxX, app.allCol(app.cuedInd), pkf);
                    else
                        mCued = zeros(1, app.nBin);
                    end
                    mTested = zeros(1, app.nBin);
                    for j = 1:nSti
                        mTested = mTested + Interference.expWeight(app.allPos(j), app.allPos(app.testedInd), ps) .*  ...
                            Interference.vonMises(xAxX, app.allCol(j), pk);
                    end
                    if app.testedInd ~= app.cuedInd  % Uncued
                        app.xAx.Children(2).YData = pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mTested + mAct + mNoise;
                    else  % Cued
                        app.xAx.Children(2).YData = mTested + pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mAct + mNoise;
                    end
                    
            end
            
            pause(1 / app.rfRate);
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            
            % Constants
            pa = app.aSlider.Value;
            pb = event.Value;
            ps = app.sSlider.Value;
            pr = app.rSlider.Value;
            pk = app.kSlider.Value;
            pkf = app.kfSlider.Value;
            nSti = length(app.allPos);
            xAxX = 0:(app.nBin - 1);
            
            mNoise = nSti / 2 / pi * pb * ones(1, app.nBin);
            mAct = zeros(1, app.nBin);
            for i = 1:nSti
                mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
            end
            
            switch app.Stage
                case 'Init'
                    return
                case 'New item'
                    for i = 1:nSti
                        app.xAx.Children(end + 1 - i).YData = ...
                            Interference.vonMises(xAxX, app.allCol(i), pk) + pb / 2 / pi;
                    end
                case 'Display'
                    mAll = zeros(1, app.nBin);
                    for i = 1:nSti
                        mAll = mAll +  ...
                            Interference.vonMises(xAxX, app.allCol(i), pk);
                    end
                    app.xAx.Children(1).YData = mAll + mNoise;
                case 'Cue'  % Precue
                    mAll = zeros(1, app.nBin);
                    for i = 1:nSti
                        mAll = mAll +  ...
                            Interference.vonMises(xAxX, app.allCol(i), pk);
                    end
                    app.xAx.Children(2).YData = mAll + mNoise;
                case 'Retention'
                    
                    if length(app.xAx.Children) ~= 3  % During testing
                        return
                    end
                    
%                     currYlim = ylim(app.xAx);
                    
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCue = Interference.vonMises(xAxX, ...
                            app.allCol(app.cuedInd), pkf);
                    else
                        mCue = 0;
                    end                    
                    
                    app.xAx.Children(3).YData = pr * (mNoise + mAct) + mCue;
                    app.xAx.Children(2).YData = mAct + mNoise;
                    app.xAx.Children(1).YData = mNoise;
%                     ylim(app.xAx, currYlim);
                    
                case 'Test'
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCued = Interference.vonMises(xAxX, app.allCol(app.cuedInd), pkf);
                    else
                        mCued = zeros(1, app.nBin);
                    end
                    mTested = zeros(1, app.nBin);
                    for j = 1:nSti
                        mTested = mTested + Interference.expWeight(app.allPos(j), app.allPos(app.testedInd), ps) .*  ...
                            Interference.vonMises(xAxX, app.allCol(j), pk);
                    end
                    if app.testedInd ~= app.cuedInd  % Uncued
                        app.xAx.Children(2).YData = pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mTested + mAct + mNoise;
                    else  % Cued
                        app.xAx.Children(2).YData = mTested + pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mAct + mNoise;
                    end
                    
            end
            
            pause(1 / app.rfRate);            
        end

        % Value changing function: kSlider
        function kSliderValueChanging(app, event)
            
            % Constants
            pa = app.aSlider.Value;
            pb = app.bSlider.Value;
            pr = app.rSlider.Value;
            pk = event.Value;
            ps = app.sSlider.Value;
            pkf = app.kfSlider.Value;
            nSti = length(app.allPos);
            xAxX = 0:(app.nBin - 1);
            
            mNoise = nSti / 2 / pi * pb * ones(1, app.nBin);
            mAct = zeros(1, app.nBin);
            for i = 1:nSti
                mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
            end
            
            switch app.Stage
                case 'Init'
                    return
                case 'New item'
                    for i = 1:nSti
                        app.xAx.Children(end + 1 - i).YData = ...
                            Interference.vonMises(xAxX, app.allCol(i), pk) + pb / 2 / pi;
                    end
                case 'Display'
                    mAll = zeros(1, app.nBin);
                    for i = 1:nSti
                        mAll = mAll +  ...
                            Interference.vonMises(xAxX, app.allCol(i), pk);
                    end
                    app.xAx.Children(1).YData = mAll + mNoise;
                case 'Cue'  % Precue
                    mAll = zeros(1, app.nBin);
                    for i = 1:nSti
                        mAll = mAll +  ...
                            Interference.vonMises(xAxX, app.allCol(i), pk);
                    end
                    app.xAx.Children(2).YData = mAll + mNoise;
                case 'Retention'
                    
                    if length(app.xAx.Children) ~= 3  % During testing
                        return
                    end
                    
%                     currYlim = ylim(app.xAx);
                    
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCue = Interference.vonMises(xAxX, ...
                            app.allCol(app.cuedInd), pkf);
                    else
                        mCue = 0;
                    end                    
                    
                    app.xAx.Children(3).YData = pr * (mNoise + mAct) + mCue;
                    app.xAx.Children(2).YData = mAct + mNoise;
                    app.xAx.Children(1).YData = mNoise;
%                     ylim(app.xAx, currYlim);
                    
                case 'Test'
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCued = Interference.vonMises(xAxX, app.allCol(app.cuedInd), pkf);
                    else
                        mCued = zeros(1, app.nBin);
                    end
                    mTested = zeros(1, app.nBin);
                    for j = 1:nSti
                        mTested = mTested + Interference.expWeight(app.allPos(j), app.allPos(app.testedInd), ps) .*  ...
                            Interference.vonMises(xAxX, app.allCol(j), pk);
                    end
                    if app.testedInd ~= app.cuedInd  % Uncued
                        app.xAx.Children(2).YData = pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mTested + mAct + mNoise;
                    else  % Cued
                        app.xAx.Children(2).YData = mTested + pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mAct + mNoise;
                    end
                    
            end
            
            pause(1 / app.rfRate);             
        end

        % Value changing function: kfSlider
        function kfSliderValueChanging(app, event)
            
            % Constants
            pa = app.aSlider.Value;
            pb = app.bSlider.Value;
            pr = app.rSlider.Value;
            pk = app.kSlider.Value;
            ps = app.sSlider.Value;
            pkf = event.Value;
            nSti = length(app.allPos);
            xAxX = 0:(app.nBin - 1);
            
            mNoise = nSti / 2 / pi * pb * ones(1, app.nBin);
            mAct = zeros(1, app.nBin);
            for i = 1:nSti
                mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
            end
            
            switch app.Stage
                case {'Init', 'New item', 'Display'}
                    return
                case 'Cue'  % Precue
                    app.xAx.Children(1).YData = Interference.vonMises(xAxX, app.allCol(app.cuedInd), pkf);
                case 'Retention'
                    
                    if length(app.xAx.Children) ~= 3  % During testing
                        return
                    end
                    
                    
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCue = Interference.vonMises(xAxX, ...
                            app.allCol(app.cuedInd), pkf);
                    else
                        mCue = 0;
                    end                    
                    
                    app.xAx.Children(3).YData = pr * (mNoise + mAct) + mCue;
%                     ylim(app.xAx, currYlim);
                    
                case 'Test'
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCued = Interference.vonMises(xAxX, app.allCol(app.cuedInd), pkf);
                    else
                        mCued = zeros(1, app.nBin);
                    end
                    mTested = zeros(1, app.nBin);
                    for j = 1:nSti
                        mTested = mTested + Interference.expWeight(app.allPos(j), app.allPos(app.testedInd), ps) .*  ...
                            Interference.vonMises(xAxX, app.allCol(j), pk);
                    end
                    if app.testedInd ~= app.cuedInd  % Uncued
                        app.xAx.Children(2).YData = pr * (mAct + mNoise) + mCued;
%                         app.xAx.Children(1).YData = mTested + mAct + mNoise;
                    else  % Cued
                        app.xAx.Children(2).YData = mTested + pr * (mAct + mNoise) + mCued;
%                         app.xAx.Children(1).YData = mAct + mNoise;
                    end
                    
            end
            
            pause(1 / app.rfRate);
        end

        % Value changing function: sSlider
        function sSliderValueChanging(app, event)
            
            % Constants
            pa = app.aSlider.Value;
            pb = app.bSlider.Value;
            pr = app.rSlider.Value;
            pk = app.kSlider.Value;
            ps = event.Value;
            pkf = app.kfSlider.Value;
            nSti = length(app.allPos);
            xAxX = 0:(app.nBin - 1);
            
            mNoise = nSti / 2 / pi * pb * ones(1, app.nBin);
            mAct = zeros(1, app.nBin);
            for i = 1:nSti
                mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
            end
            
            switch app.Stage
                case 'Init'
                    return
                case 'New item'
                    for i = 1:nSti
                        app.xAx.Children(end + 1 - i).YData =  ...
                            Interference.vonMises(xAxX, app.allCol(i), pk) + pb / 2 / pi;
                    end
                case 'Display'
                    mAll = zeros(1, app.nBin);
                    for i = 1:nSti
                        mAll = mAll + ...
                            Interference.vonMises(xAxX, app.allCol(i), pk);
                    end
                    app.xAx.Children(1).YData = mAll + mNoise;
                case 'Cue'  % Precue
                    mAll = zeros(1, app.nBin);
                    for i = 1:nSti
                        mAll = mAll +  ...
                            Interference.vonMises(xAxX, app.allCol(i), pk);
                    end
                    app.xAx.Children(2).YData = mAll + mNoise;
                case 'Retention'
                    return
                case 'Test'
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCued = Interference.vonMises(xAxX, app.allCol(app.cuedInd), pkf);
                    else
                        mCued = zeros(1, app.nBin);
                    end
                    mTested = zeros(1, app.nBin);
                    for j = 1:nSti
                        mTested = mTested + Interference.expWeight(app.allPos(j), app.allPos(app.testedInd), ps) .*  ...
                            Interference.vonMises(xAxX, app.allCol(j), pk);
                    end
                    if app.testedInd ~= app.cuedInd  % Uncued
                        app.xAx.Children(2).YData = pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mTested + mAct + mNoise;
                    else  % Cued
                        app.xAx.Children(2).YData = mTested + pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mAct + mNoise;
                    end
                    
            end
            
            pause(1 / app.rfRate);   
        end

        % Value changing function: rSlider
        function rSliderValueChanging(app, event)
            
            % Constants
            pa = app.aSlider.Value;
            pb = app.bSlider.Value;
            pr = event.Value;
            pk = app.kSlider.Value;
            ps = app.sSlider.Value;
            pkf = app.kfSlider.Value;
            nSti = length(app.allPos);
            xAxX = 0:(app.nBin - 1);
            
            mNoise = nSti / 2 / pi * pb * ones(1, app.nBin);
            mAct = zeros(1, app.nBin);
            for i = 1:nSti
                mAct = mAct + pa * Interference.vonMises(xAxX, app.allCol(i), pk);
            end
            
            switch app.Stage
                case {'Init', 'New item', 'Display', 'Cue'}
                    return
                case 'Retention'
                    
                    if length(app.xAx.Children) ~= 3  % During testing
                        return
                    end
                    
%                     currYlim = ylim(app.xAx);
                    
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCue = Interference.vonMises(xAxX, ...
                            app.allCol(app.cuedInd), pkf);
                    else
                        mCue = 0;
                    end                    
                    
                    app.xAx.Children(3).YData = pr * (mNoise + mAct) + mCue;
                    app.xAx.Children(2).YData = mAct + mNoise;
                    app.xAx.Children(1).YData = mNoise;
%                     ylim(app.xAx, currYlim);
                    
                case 'Test'
                    if app.cuedInd == nSti || app.PreButton.Value
                        mCued = Interference.vonMises(xAxX, app.allCol(app.cuedInd), pkf);
                    else
                        mCued = zeros(1, app.nBin);
                    end
                    mTested = zeros(1, app.nBin);
                    for j = 1:nSti
                        mTested = mTested + Interference.expWeight(app.allPos(j), app.allPos(app.testedInd), ps) .*  ...
                            Interference.vonMises(xAxX, app.allCol(j), pk);
                    end
                    if app.testedInd ~= app.cuedInd  % Uncued
                        app.xAx.Children(2).YData = pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mTested + mAct + mNoise;
                    else  % Cued
                        app.xAx.Children(2).YData = mTested + pr * (mAct + mNoise) + mCued;
                        app.xAx.Children(1).YData = mAct + mNoise;
                    end
                    
            end
            
            pause(1 / app.rfRate);   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WMmodel = uifigure;
            app.WMmodel.Name = 'Interference Model for Working Memory';
            app.WMmodel.WindowButtonDownFcn = createCallbackFcn(app, @WMmodelWindowButtonDown, true);
            app.WMmodel.WindowButtonMotionFcn = createCallbackFcn(app, @WMmodelWindowButtonMotion, true);
            app.Parameters = uipanel(app.WMmodel);
            app.Parameters.Title = 'Parameters';
            app.aSliderLabel = uilabel(app.Parameters);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.Parameters);
            app.aSlider.Limits = [0 1];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.bSliderLabel = uilabel(app.Parameters);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.Parameters);
            app.bSlider.Limits = [0 1];
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.SliderLabel = uilabel(app.Parameters);
            app.SliderLabel.Text = 'κ';
            app.kSlider = uislider(app.Parameters);
            app.kSlider.Limits = [1 50];
            app.kSlider.ValueChangingFcn = createCallbackFcn(app, @kSliderValueChanging, true);
            app.kSlider.Value = 1;
            app.fSliderLabel = uilabel(app.Parameters);
            app.fSliderLabel.Text = 'κf';
            app.kfSlider = uislider(app.Parameters);
            app.kfSlider.Limits = [1 30];
            app.kfSlider.ValueChangingFcn = createCallbackFcn(app, @kfSliderValueChanging, true);
            app.kfSlider.Value = 1;
            app.sSliderLabel = uilabel(app.Parameters);
            app.sSliderLabel.Text = 's';
            app.sSlider = uislider(app.Parameters);
            app.sSlider.Limits = [0.2 10];
            app.sSlider.ValueChangingFcn = createCallbackFcn(app, @sSliderValueChanging, true);
            app.sSlider.Value = 0.2;
            app.rSliderLabel = uilabel(app.Parameters);
            app.rSliderLabel.Text = 'r';
            app.rSlider = uislider(app.Parameters);
            app.rSlider.Limits = [0 1];
            app.rSlider.ValueChangingFcn = createCallbackFcn(app, @rSliderValueChanging, true);
            app.Disp = uiaxes(app.WMmodel);
            app.Disp.XTick = [];
            app.Disp.YTick = [];
            app.yAx = uiaxes(app.WMmodel);
            app.yAx.XAxisLocation = 'top';
            app.yAx.XTick = [];
            app.yAx.YAxisLocation = 'right';
            app.yAx.YTick = [];
            app.Bind = uiaxes(app.WMmodel);
            app.Bind.XTick = [];
            app.Bind.YTick = [];
            app.Colorbar = uiaxes(app.WMmodel);
            app.Colorbar.XTick = [];
            app.Colorbar.YTick = [];
            app.xAx = uiaxes(app.WMmodel);
            app.xAx.XTick = [];
            app.xAx.YTick = [];
            app.NewitemButton = uibutton(app.WMmodel, 'push');
            app.NewitemButton.ButtonPushedFcn = createCallbackFcn(app, @NewitemButtonPushed, true);
            app.NewitemButton.Text = 'New item';
            app.CueTypeButtonGroup = uibuttongroup(app.WMmodel);
            app.CueTypeButtonGroup.Title = 'Cue Type';
            app.NoneButton = uiradiobutton(app.CueTypeButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.PreButton = uiradiobutton(app.CueTypeButtonGroup);
            app.PreButton.Text = 'Pre';
            app.RetroButton = uiradiobutton(app.CueTypeButtonGroup);
            app.RetroButton.Text = 'Retro';
            app.DisplayButton = uibutton(app.WMmodel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.RetentionButton = uibutton(app.WMmodel, 'push');
            app.RetentionButton.ButtonPushedFcn = createCallbackFcn(app, @RetentionButtonPushed, true);
            app.RetentionButton.Text = 'Retention';
            app.CueButton = uibutton(app.WMmodel, 'push');
            app.CueButton.ButtonPushedFcn = createCallbackFcn(app, @CueButtonPushed, true);
            app.CueButton.Text = 'Cue';
            app.TestButton = uibutton(app.WMmodel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.ClearButton = uibutton(app.WMmodel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.InterferenceModelforWorkingMemoryLabel = uilabel(app.WMmodel);
            app.InterferenceModelforWorkingMemoryLabel.Text = {'Interference Model for'; 'Working Memory'};
            app.byRuiqiChenLabel = uilabel(app.WMmodel);
            app.byRuiqiChenLabel.Text = 'by Ruiqi Chen';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interference

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WMmodel)

            % Execute the startup function
            runStartupFcn(app, @Init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WMmodel)
        end
    end
end